classdef extractROIvalue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        VisualizeROICheckBox    matlab.ui.control.CheckBox
        GridLayout4             matlab.ui.container.GridLayout
        MethodDropDown          matlab.ui.control.DropDown
        MethodDropDownLabel     matlab.ui.control.Label
        InputFileNametxtdatcsvxlsxlsmxlsxLabel  matlab.ui.control.Label
        InputFileNameEditField  matlab.ui.control.EditField
        FilePathTextArea        matlab.ui.control.TextArea
        SelectFilePathButton    matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        AtlasTextArea           matlab.ui.control.TextArea
        SelectAtlasImageButton  matlab.ui.control.Button
        GridLayout2             matlab.ui.container.GridLayout
        SelectPETImagesButton   matlab.ui.control.Button
        PETnamesTextArea        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 510 && h >496
                app.UIFigure.Position = [round((w-510)/2)+1,round((h-496)/2)+1,510,490];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: SelectPETImagesButton
        function SelectPETImagesButtonPushed(app, event)
            PETnames = spm_select(Inf,'image','Select PET images'); 
            if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
            end
        end

        % Button pushed function: SelectAtlasImageButton
        function SelectAtlasImageButtonPushed(app, event)
            str = which('extractROIvalue');
            [filepath,~,~] = fileparts(str);
            atlasName = spm_select(Inf,'image','Select Reference Region image(s)',{},fullfile(filepath,'Atlas')); 
            if ~isempty(atlasName)
                app.AtlasTextArea.Value = cellstr(atlasName);
                if size(atlasName,1) == 1
                    [filepath,name,~] = fileparts(atlasName);
                    LablesName = fullfile(filepath,[name,'_Labels.mat']);
                    if exist(LablesName,'file') == 2
                        app.AtlasTextArea.UserData = load(LablesName);
                        fprintf('Label exist\n');
                    else
                        app.AtlasTextArea.UserData = 0;
                    end
                else
                    app.AtlasTextArea.UserData = 0;
                end

                validity = checkAtlasValidity(app.AtlasTextArea.Value, app.MethodDropDown.Value);
                app.MethodDropDown.Items = validity.allowedMethods;
                if ~validity.isMethodAllowed
                    app.MethodDropDown.Value = validity.allowedMethods{1};
                end
                if ~isempty(validity.selectionMsg)
                    uialert(app.UIFigure, validity.selectionMsg, 'Hint', 'Icon', 'warning');
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Extract the ROI values');
            PETnames = app.PETnamesTextArea.Value;
            atlasName = app.AtlasTextArea.Value;
            ROIvaluefilepath = app.FilePathTextArea.Value;
            filename = app.InputFileNameEditField.Value;
            extraROIMethod = app.MethodDropDown.Value;

            if isempty(PETnames{1,1})
                uialert(app.UIFigure,'No PET image entered.','Alert');
            elseif isempty(atlasName{1,1})
                uialert(app.UIFigure,'No Reference Region image entered.','Alert');
            elseif isempty(filename)
                uialert(app.UIFigure,'Please Input File Name.','Alert');
            elseif isempty(ROIvaluefilepath)
                uialert(app.UIFigure,'Please Select File Path.','Alert');
            else
                ROIvaluefilefullname = fullfile(ROIvaluefilepath{1},filename);
                validity = checkAtlasValidity(atlasName, extraROIMethod);
                if ~validity.isMethodAllowed
                    
                    uialert(app.UIFigure, validity.runMsg, 'Wrong Method', 'Icon', 'error');
                    close(d);
                    return;
                end

                if validity.needConfirm
                    close(d); 
                    answer = uiconfirm(app.UIFigure, validity.runMsg, 'Make sure', ...
                        'Options', {'Continue', 'Cancel'}, ...
                        'DefaultOption', 'Continue', ...
                        'CancelOption', 'Cancel');
                    if strcmp(answer, 'Cancel')
                        return;
                    end
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
                        'Message', 'Extract the ROI values');
                end

                if isstruct(app.AtlasTextArea.UserData)
                    atlasLabels = app.AtlasTextArea.UserData.Labels;
                    ROIvalueCell = makeROIValueCell(PETnames,atlasName,extraROIMethod,d,atlasLabels);
                else
                    ROIvalueCell = makeROIValueCell(PETnames,atlasName,extraROIMethod,d);
                end
                writecell(ROIvalueCell,ROIvaluefilefullname);
                [~,name,~] = fileparts(ROIvaluefilefullname);
                name = matlab.lang.makeValidName(name);
                T = cell2table(ROIvalueCell(2:end,:));
                T.Properties.VariableNames = cellstr(ROIvalueCell(1,:));
                assignin("base",name,T);
                fprintf('Down: Extracting ROI Values.\n');
            end
            
            close(d);
            if app.VisualizeROICheckBox.Value
            % Gather the same inputs that were used for extraction
            % (imageNames, atlasNames, extraROIMethod, atlasLabels must be
            %  in scope from earlier in this callback)

            try
                if isstruct(app.AtlasTextArea.UserData)  
                    atlasLabels = app.AtlasTextArea.UserData.Labels;
                    roiOverlayViewer(PETnames, atlasName, ...
                                         atlasLabels);
                else
                    roiOverlayViewer(PETnames, atlasName);
                end
            catch ME
                uialert(app.UIFigure, ...
                    ['ROI visualisation failed: ' ME.message], ...
                    'Visualisation Error', 'Icon', 'warning');
            end
            end
          
        end

        % Button pushed function: SelectFilePathButton
        function SelectFilePathButtonPushed(app, event)
            ROIvaluefilepath = uigetdir();
            if ROIvaluefilepath ~= 0
                app.FilePathTextArea.Value = ROIvaluefilepath;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.SelectPETImagesButton = uibutton(app.GridLayout2, 'push');
            app.SelectPETImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPETImagesButtonPushed, true);
            app.SelectPETImagesButton.Text = 'Select PET Images';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SelectAtlasImageButton = uibutton(app.GridLayout3, 'push');
            app.SelectAtlasImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasImageButtonPushed, true);
            app.SelectAtlasImageButton.Text = 'Select Atlas Image';
            app.AtlasTextArea = uitextarea(app.GridLayout3);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectFilePathButton = uibutton(app.GridLayout3, 'push');
            app.SelectFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilePathButtonPushed, true);
            app.SelectFilePathButton.Text = ' Select File Path';
            app.FilePathTextArea = uitextarea(app.GridLayout3);
            app.InputFileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.InputFileNametxtdatcsvxlsxlsmxlsxLabel = uilabel(app.GridLayout3);
            app.InputFileNametxtdatcsvxlsxlsmxlsxLabel.Text = {'Input File Name '; '(.txt, .dat, .csv, '; '.xls, .xlsm, .xlsx)'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.MethodDropDownLabel = uilabel(app.GridLayout4);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout4);
            app.MethodDropDown.Items = {'mean', 'median', 'weight'};
            app.MethodDropDown.Value = 'mean';
            app.VisualizeROICheckBox = uicheckbox(app.GridLayout3);
            app.VisualizeROICheckBox.Text = 'Visualize extracted ROIs after extraction';
            app.VisualizeROICheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extractROIvalue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end