classdef PETIntensityNormalize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        FWHMEditField                matlab.ui.control.EditField
        FWHMEditFieldLabel           matlab.ui.control.Label
        SmooththeimageafterintensitynormalizationCheckBox  matlab.ui.control.CheckBox
        GridLayout5                  matlab.ui.container.GridLayout
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        DisplaythelocationofthereferenceregionCheckBox  matlab.ui.control.CheckBox
        GridLayout4                  matlab.ui.container.GridLayout
        PrefixEditField              matlab.ui.control.EditField
        PrefixEditFieldLabel         matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        ReferenceRegionTextArea      matlab.ui.control.TextArea
        SelectReferenceRegionButton  matlab.ui.control.Button
        GridLayout2                  matlab.ui.container.GridLayout
        SelectPETImagesButton        matlab.ui.control.Button
        PETnamesTextArea             matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 510 && h >496
                app.UIFigure.Position = [round((w-510)/2)+1,round((h-496)/2)+1,510,490];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: SelectPETImagesButton
        function SelectPETImagesButtonPushed(app, event)
            PETnames = spm_select(Inf,'image','Select PET images'); 
            if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
            end
        end

        % Button pushed function: SelectReferenceRegionButton
        function SelectReferenceRegionButtonPushed(app, event)
            str = which('PETIntensityNormalize');
            [filepath,~,~] = fileparts(str);
            ReferenceName = spm_select(1,'image','Select Reference Region image',{},fullfile(filepath,'ReferenceRegion')); 
            if ~isempty(ReferenceName)
                app.ReferenceRegionTextArea.Value = cellstr(ReferenceName);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            PETnames = app.PETnamesTextArea.Value;
            ReferenceName = app.ReferenceRegionTextArea.Value;
            prefix = app.PrefixEditField.Value;
            intenNormMethod = app.MethodDropDown.Value;
            isSmooth = app.SmooththeimageafterintensitynormalizationCheckBox.Value;
            fwhmStr = app.FWHMEditField.Value;
            if strcmp(intenNormMethod, 'mean')
                intenNormMethodID = 1;
            elseif strcmp(intenNormMethod,'median')
                intenNormMethodID = 2;
            end
            if isempty(PETnames{1,1})
                uialert(app.UIFigure,'No PET image entered.','Alert');
            elseif isempty(ReferenceName{1,1})
                uialert(app.UIFigure,'No Reference Region image entered.','Alert');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Intensity Normalization is in progress');
                [meanImg,ReferenceMask] = IntensityNormalize(PETnames,ReferenceName,prefix,intenNormMethodID,d);
                
                if isSmooth
                    d.Message = 'Smoothing is in progress';
                    SmoothNormalizedImages(PETnames, prefix, fwhmStr, app.UIFigure);
                end
                close(d);
                if app.DisplaythelocationofthereferenceregionCheckBox.Value
                    checkReferenceMaskAlignment(meanImg, ReferenceMask);
                end
                fprintf('Down: PET Images Intensity Normalization.\n');
            end
        end

        % Value changed function: 
        % SmooththeimageafterintensitynormalizationCheckBox
        function SmooththeimageafterintensitynormalizationCheckBoxValueChanged(app, event)
            value = app.SmooththeimageafterintensitynormalizationCheckBox.Value;
            if value
                app.FWHMEditField.Enable = 'on';
            else
                app.FWHMEditField.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.SelectPETImagesButton = uibutton(app.GridLayout2, 'push');
            app.SelectPETImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPETImagesButtonPushed, true);
            app.SelectPETImagesButton.Text = 'Select PET Images';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SelectReferenceRegionButton = uibutton(app.GridLayout3, 'push');
            app.SelectReferenceRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceRegionButtonPushed, true);
            app.SelectReferenceRegionButton.Text = 'Select Reference Region';
            app.ReferenceRegionTextArea = uitextarea(app.GridLayout3);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PrefixEditFieldLabel = uilabel(app.GridLayout4);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.GridLayout4, 'text');
            app.PrefixEditField.Value = 'n';
            app.DisplaythelocationofthereferenceregionCheckBox = uicheckbox(app.GridLayout3);
            app.DisplaythelocationofthereferenceregionCheckBox.Text = 'Display the location of the reference region';
            app.DisplaythelocationofthereferenceregionCheckBox.Value = true;
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.MethodDropDownLabel = uilabel(app.GridLayout5);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout5);
            app.MethodDropDown.Items = {'mean', 'median'};
            app.MethodDropDown.Value = 'mean';
            app.SmooththeimageafterintensitynormalizationCheckBox = uicheckbox(app.GridLayout3);
            app.SmooththeimageafterintensitynormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmooththeimageafterintensitynormalizationCheckBoxValueChanged, true);
            app.SmooththeimageafterintensitynormalizationCheckBox.Text = 'Smooth the image after intensity normalization';
            app.SmooththeimageafterintensitynormalizationCheckBox.Value = true;
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.FWHMEditFieldLabel = uilabel(app.GridLayout6);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.GridLayout6, 'text');
            app.FWHMEditField.Value = '8 8 8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PETIntensityNormalize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end