classdef AtlasBased < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        NormalisePrefixEditField       matlab.ui.control.EditField
        NormalisePrefixEditFieldLabel  matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        RegularTermEditField           matlab.ui.control.NumericEditField
        RegularTermEditFieldLabel      matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        VoxelSizesEditField            matlab.ui.control.EditField
        VoxelSizesEditFieldLabel       matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        BoundingBoxEditField           matlab.ui.control.EditField
        BoundingBoxEditFieldLabel      matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        threadsnumEditField            matlab.ui.control.NumericEditField
        threadsnumEditFieldLabel       matlab.ui.control.Label
        RuninBackgroundCheckBox        matlab.ui.control.CheckBox
        SaveDeformationCheckBox        matlab.ui.control.CheckBox
        SaveAdaptiveTPMCheckBox        matlab.ui.control.CheckBox
        OptionsLabel                   matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        PETnamesTextArea               matlab.ui.control.TextArea
        PETDataButton                  matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 700 && h >480
                app.UIFigure.Position = [round((w-700)/2)+1,round((h-480)/2)+1,700,480];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: PETDataButton
        function PETDataButtonPushed(app, event)
           PETnames = spm_select(Inf,'image','Select PET images');
           if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
           end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Capture the GUI's working directory FIRST. Background workers are
            % spawned in a clean environment whose pwd is NOT the GUI's pwd, so
            % we must read it here and pass it through.
            runDir = pwd;
        
            % Read all GUI inputs.
            PETnames    = app.PETnamesTextArea.Value;
            bbox        = str2num(app.BoundingBoxEditField.Value); %#ok<ST2NM>
            voxelsize   = str2num(app.VoxelSizesEditField.Value);  %#ok<ST2NM>
            [bx, by]    = size(bbox);
            [vxsz, vy]  = size(voxelsize);
            normprefix  = app.NormalisePrefixEditField.Value;
            regularterm = app.RegularTermEditField.Value;
            saveTPM     = app.SaveAdaptiveTPMCheckBox.Value;
            saveDeform  = app.SaveDeformationCheckBox.Value;
            runBg       = app.RuninBackgroundCheckBox.Value;
            nProc       = max(1, round(app.threadsnumEditField.Value));
        
            % --- Validate inputs ---------------------------------------------
            if isempty(PETnames{1, 1})
                uialert(app.UIFigure, 'PET images cannot be empty.', 'Alert');
                return;
            elseif bx ~= 2 || by ~= 3
                uialert(app.UIFigure, 'The size of bounding box should be 2*3.', 'Alert');
                return;
            elseif vxsz ~= 1 || vy ~= 3
                uialert(app.UIFigure, 'The size of voxel size should be 1*3.', 'Alert');
                return;
            end
        
            if runBg
                % ============== BACKGROUND MODE ==============
                nProcEff = min(nProc, numel(PETnames));
                % Pass runDir so logs go to the GUI's working directory.
                tmpDir = AtlasBased_dispatch(PETnames, bbox, voxelsize, ...
                            normprefix, regularterm, saveTPM, saveDeform, ...
                            nProcEff, true, runDir);
        
                uialert(app.UIFigure, ...
                    sprintf(['Launched %d background MATLAB processes.\n\n' ...
                             'You can safely close this GUI; they will keep running.\n\n' ...
                             'Logs and status files are in:\n%s\n\n' ...
                             'Failed subjects are recorded as [FAIL] lines in part_*_log.txt'], ...
                            nProcEff, tmpDir), ...
                    'Background run started', 'Icon', 'success');
                fprintf('Background dispatch done. Workspace: %s\n', tmpDir);
        
            else
                % ============== FOREGROUND MODE ==============
                d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
                    'Message', 'Adaptive TPM based Spatial Normalization is in progress', ...
                    'Indeterminate', 'off');
        
                failed = AtlasBasedSpatialNormalizationMethod( ...
                    PETnames, bbox, voxelsize, ...
                    normprefix, regularterm, d, saveTPM, saveDeform);
        
                close(d);
        
                % Write a log file ONLY when there were failures, into runDir.
                logPath = '';
                if ~isempty(failed)
                    logDir = fullfile(runDir, ...
                        ['SNBPI_logs_' datestr(now, 'yyyymmdd_HHMMSS')]);
                    if ~exist(logDir, 'dir'); mkdir(logDir); end
                    logPath = fullfile(logDir, 'foreground_log.txt');
                    fid = fopen(logPath, 'w');
                    fprintf(fid, 'SNBPI foreground run @ %s\n', datestr(now));
                    fprintf(fid, 'OK=%d FAIL=%d TOTAL=%d\n\n', ...
                        numel(PETnames) - numel(failed), ...
                        numel(failed), numel(PETnames));
                    for i = 1:numel(failed)
                        fprintf(fid, '[FAIL] %s\n', failed{i});
                    end
                    fclose(fid);
                end
        
                if isempty(failed)
                    uialert(app.UIFigure, ...
                        'All subjects completed successfully.', ...
                        'Done', 'Icon', 'success');
                else
                    msg = sprintf('Done with %d failure(s):\n\n%s\n\nLog saved to:\n%s', ...
                        numel(failed), strjoin(failed, newline), logPath);
                    uialert(app.UIFigure, msg, ...
                        'Done with failures', 'Icon', 'warning');
                end
                fprintf('Done: Atlas Based Spatial Normalization.\n');
            end
        end

        % Value changed function: RuninBackgroundCheckBox
        function RuninBackgroundCheckBoxValueChanged(app, event)
            value = app.RuninBackgroundCheckBox.Value;
            if value 
                app.threadsnumEditField.Enable = 'on';
            else
                app.threadsnumEditField.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETDataButton = uibutton(app.GridLayout2, 'push');
            app.PETDataButton.ButtonPushedFcn = createCallbackFcn(app, @PETDataButtonPushed, true);
            app.PETDataButton.Text = 'PET Data';
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.OptionsLabel = uilabel(app.GridLayout3);
            app.OptionsLabel.Text = 'Options';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SaveAdaptiveTPMCheckBox = uicheckbox(app.GridLayout4);
            app.SaveAdaptiveTPMCheckBox.Text = '   Save Adaptive TPM';
            app.SaveDeformationCheckBox = uicheckbox(app.GridLayout4);
            app.SaveDeformationCheckBox.Text = 'Save Deformation';
            app.SaveDeformationCheckBox.Value = true;
            app.RuninBackgroundCheckBox = uicheckbox(app.GridLayout4);
            app.RuninBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RuninBackgroundCheckBoxValueChanged, true);
            app.RuninBackgroundCheckBox.Text = 'Run in Background';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.threadsnumEditFieldLabel = uilabel(app.GridLayout10);
            app.threadsnumEditFieldLabel.Enable = 'off';
            app.threadsnumEditFieldLabel.Text = 'threads num';
            app.threadsnumEditField = uieditfield(app.GridLayout10, 'numeric');
            app.threadsnumEditField.Enable = 'off';
            app.threadsnumEditField.Value = 2;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.RunButton = uibutton(app.GridLayout5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ParametersLabel = uilabel(app.GridLayout5);
            app.ParametersLabel.Text = 'Parameters';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.BoundingBoxEditFieldLabel = uilabel(app.GridLayout6);
            app.BoundingBoxEditFieldLabel.Text = 'Bounding Box';
            app.BoundingBoxEditField = uieditfield(app.GridLayout6, 'text');
            app.BoundingBoxEditField.Value = '-90,-126,-72;90,90,108';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.VoxelSizesEditFieldLabel = uilabel(app.GridLayout7);
            app.VoxelSizesEditFieldLabel.Text = 'Voxel Sizes';
            app.VoxelSizesEditField = uieditfield(app.GridLayout7, 'text');
            app.VoxelSizesEditField.Value = '1.5, 1.5, 1.5';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.RegularTermEditFieldLabel = uilabel(app.GridLayout8);
            app.RegularTermEditFieldLabel.Text = 'Regular Term ';
            app.RegularTermEditField = uieditfield(app.GridLayout8, 'numeric');
            app.RegularTermEditField.Limits = [0 Inf];
            app.RegularTermEditField.Value = 15;
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.NormalisePrefixEditFieldLabel = uilabel(app.GridLayout9);
            app.NormalisePrefixEditFieldLabel.Text = 'Normalise Prefix';
            app.NormalisePrefixEditField = uieditfield(app.GridLayout9, 'text');
            app.NormalisePrefixEditField.Value = 'wAtlas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtlasBased

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end