classdef MRIBased < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        ParametersLabel                 matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        NormalisePrefixEditField        matlab.ui.control.EditField
        NormalisePrefixEditFieldLabel   matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        CoregisterPrefixEditField       matlab.ui.control.EditField
        CoregisterPrefixEditFieldLabel  matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        VoxelSizesEditField             matlab.ui.control.EditField
        VoxelSizesEditFieldLabel        matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        BoundingBoxEditField            matlab.ui.control.EditField
        BoundingBoxEditFieldLabel       matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        PETnamesTextArea                matlab.ui.control.TextArea
        PETDataButton                   matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        MRInamesTextArea                matlab.ui.control.TextArea
        MRIDataButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 700 && h >480
                app.UIFigure.Position = [round((w-700)/2)+1,round((h-480)/2)+1,700,480];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: MRIDataButton
        function MRIDataButtonPushed(app, event)
            MRInames = spm_select(Inf,'image','Select MRI images'); 
            if ~isempty(MRInames)
                app.MRInamesTextArea.Value = cellstr(MRInames);
            end
        end

        % Button pushed function: PETDataButton
        function PETDataButtonPushed(app, event)
           PETnames = spm_select(Inf,'image','Select PET images');
           if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
           end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            MRInames = app.MRInamesTextArea.Value;
            PETnames = app.PETnamesTextArea.Value;
            MRInum = length(MRInames);
            PETnum = length(PETnames);
            bbox = str2num(app.BoundingBoxEditField.Value);
            voxelsize = str2num(app.VoxelSizesEditField.Value);
            [bx,by] = size(bbox);
            [vx,vy] = size(voxelsize);
            coregprefix = app.CoregisterPrefixEditField.Value;
            normprefix = app.NormalisePrefixEditField.Value;
            if MRInum ~= PETnum
                uialert(app.UIFigure,'The number of MRI images and PET images does not match.','Alert');
            elseif MRInum == 0
                uialert(app.UIFigure,'No MRI image entered','Alert');
            elseif bx ~=2 || by ~=3
                uialert(app.UIFigure,'The size of bounding box should be 2*3.','Alert');   
            elseif vx ~=1 || vy ~= 3
                uialert(app.UIFigure,'The size of voxel size should be 1*3.','Alert');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','MRI based Spatial Normalization is in progress');
                if strcmp(app.MethodDropDown.Value,'CAT12') 
                    MRIBasedMethod_Cat12(MRInames,PETnames,bbox,voxelsize,coregprefix,normprefix,d);
                else
                    MRIBasedMethod_SPM12(MRInames,PETnames,bbox,voxelsize,coregprefix,normprefix,d);
                end
                close(d);
                fprintf('Down: MRI Based Spatial Normalization.\n');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MRIDataButton = uibutton(app.GridLayout2, 'push');
            app.MRIDataButton.ButtonPushedFcn = createCallbackFcn(app, @MRIDataButtonPushed, true);
            app.MRIDataButton.Text = 'MRI Data';
            app.MRInamesTextArea = uitextarea(app.GridLayout2);
            app.MRInamesTextArea.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PETDataButton = uibutton(app.GridLayout3, 'push');
            app.PETDataButton.ButtonPushedFcn = createCallbackFcn(app, @PETDataButtonPushed, true);
            app.PETDataButton.Text = 'PET Data';
            app.PETnamesTextArea = uitextarea(app.GridLayout3);
            app.PETnamesTextArea.Editable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RunButton = uibutton(app.GridLayout5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.BoundingBoxEditFieldLabel = uilabel(app.GridLayout6);
            app.BoundingBoxEditFieldLabel.Text = 'Bounding Box';
            app.BoundingBoxEditField = uieditfield(app.GridLayout6, 'text');
            app.BoundingBoxEditField.Value = '-90,-126,-72;90,90,108';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.VoxelSizesEditFieldLabel = uilabel(app.GridLayout7);
            app.VoxelSizesEditFieldLabel.Text = 'Voxel Sizes';
            app.VoxelSizesEditField = uieditfield(app.GridLayout7, 'text');
            app.VoxelSizesEditField.Value = '1.5, 1.5, 1.5';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.CoregisterPrefixEditFieldLabel = uilabel(app.GridLayout8);
            app.CoregisterPrefixEditFieldLabel.Text = 'Coregister Prefix';
            app.CoregisterPrefixEditField = uieditfield(app.GridLayout8, 'text');
            app.CoregisterPrefixEditField.Value = 'r';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.NormalisePrefixEditFieldLabel = uilabel(app.GridLayout9);
            app.NormalisePrefixEditFieldLabel.Text = 'Normalise Prefix';
            app.NormalisePrefixEditField = uieditfield(app.GridLayout9, 'text');
            app.NormalisePrefixEditField.Value = 'w';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.MethodDropDownLabel = uilabel(app.GridLayout10);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout10);
            app.MethodDropDown.Items = {'CAT12', 'SPM12'};
            app.MethodDropDown.Value = 'CAT12';
            app.ParametersLabel = uilabel(app.GridLayout4);
            app.ParametersLabel.Text = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRIBased

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end