classdef TemplateBased < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TemplateImageButton            matlab.ui.control.Button
        TemplatenameTextArea           matlab.ui.control.TextArea
        GridLayout3                    matlab.ui.container.GridLayout
        PETDataButton                  matlab.ui.control.Button
        PETnamesTextArea               matlab.ui.control.TextArea
        GridLayout4                    matlab.ui.container.GridLayout
        ParametersLabel                matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        BoundingBoxEditFieldLabel      matlab.ui.control.Label
        BoundingBoxEditField           matlab.ui.control.EditField
        GridLayout6                    matlab.ui.container.GridLayout
        VoxelSizesEditFieldLabel       matlab.ui.control.Label
        VoxelSizesEditField            matlab.ui.control.EditField
        GridLayout7                    matlab.ui.container.GridLayout
        NormalisePrefixEditFieldLabel  matlab.ui.control.Label
        NormalisePrefixEditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 700 && h >480
                app.UIFigure.Position = [round((w-700)/2)+1,round((h-480)/2)+1,700,480];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: TemplateImageButton
        function TemplateImageButtonPushed(app, event)
            str = which('SNBPI');
            [filepath,~,~] = fileparts(str);
            Templatenames = spm_select(1,'image','Select Template image',{},[filepath,'\Template']); 
            if ~isempty(Templatenames)
                app.TemplatenameTextArea.Value = cellstr(Templatenames);
            end
        end

        % Button pushed function: PETDataButton
        function PETDataButtonPushed(app, event)
           PETnames = spm_select(Inf,'image','Select PET images');
           if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
           end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Templatenames = app.TemplatenameTextArea.Value;
            Templatenum = length(Templatenames);
            PETnames = app.PETnamesTextArea.Value;
            PETnum = length(PETnames);
            bbox = str2num(app.BoundingBoxEditField.Value);
            voxelsize = str2num(app.VoxelSizesEditField.Value);
            [bx,by] = size(bbox);
            [vx,vy] = size(voxelsize);
            normprefix = app.NormalisePrefixEditField.Value;
            if Templatenum ~=1
                uialert(app.UIFigure,'There must be one and only one template image.','Alert');
            elseif isempty(Templatenames{1,1})
                uialert(app.UIFigure,'No template image entered.','Alert');
            elseif isempty(PETnames{1,1})
                uialert(app.UIFigure,'PET images cannot be empty.','Alert');
            elseif  bx ~=2 || by ~=3
                uialert(app.UIFigure,'The size of bounding box should be 2*3.','Alert');   
            elseif vx ~=1 || vy ~= 3
                uialert(app.UIFigure,'The size of voxel size should be 1*3.','Alert');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Template based Spatial Normalization is in progress');
                TemplateBasedSpatialNormalizationMethod(Templatenames,PETnames,bbox,voxelsize,normprefix,d);
                close(d);
                fprintf('Down: Template Based Spatial Normalization.\n');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TemplateImageButton = uibutton(app.GridLayout2, 'push');
            app.TemplateImageButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateImageButtonPushed, true);
            app.TemplateImageButton.Text = 'Template Image';
            app.TemplatenameTextArea = uitextarea(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PETDataButton = uibutton(app.GridLayout3, 'push');
            app.PETDataButton.ButtonPushedFcn = createCallbackFcn(app, @PETDataButtonPushed, true);
            app.PETDataButton.Text = 'PET Data';
            app.PETnamesTextArea = uitextarea(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ParametersLabel = uilabel(app.GridLayout4);
            app.ParametersLabel.Text = 'Parameters';
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.BoundingBoxEditFieldLabel = uilabel(app.GridLayout5);
            app.BoundingBoxEditFieldLabel.Text = 'Bounding Box';
            app.BoundingBoxEditField = uieditfield(app.GridLayout5, 'text');
            app.BoundingBoxEditField.Value = '-90,-126,-72;90,90,108';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.VoxelSizesEditFieldLabel = uilabel(app.GridLayout6);
            app.VoxelSizesEditFieldLabel.Text = 'Voxel Sizes';
            app.VoxelSizesEditField = uieditfield(app.GridLayout6, 'text');
            app.VoxelSizesEditField.Value = '1.5, 1.5, 1.5';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.NormalisePrefixEditFieldLabel = uilabel(app.GridLayout7);
            app.NormalisePrefixEditFieldLabel.Text = 'Normalise Prefix';
            app.NormalisePrefixEditField = uieditfield(app.GridLayout7, 'text');
            app.NormalisePrefixEditField.Value = 'w';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TemplateBased

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end