classdef SNBPI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToolsMenu                   matlab.ui.container.Menu
        SetOriginToCentroidMenu     matlab.ui.container.Menu
        SpatialNormalizationQualityCheckMenu  matlab.ui.container.Menu
        IntensityNormalizationMenu  matlab.ui.container.Menu
        SmoothMenu                  matlab.ui.container.Menu
        AtlasMergerMenu             matlab.ui.container.Menu
        ExtractROIValuesMenu        matlab.ui.container.Menu
        OverlayViewerMenu           matlab.ui.container.Menu
        DeleteFilesMenu             matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        CheckforUpdatesMenu         matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        GridLayout2                 matlab.ui.container.GridLayout
        AtlasBasedButton            matlab.ui.control.Button
        TemplateBasedButton         matlab.ui.control.Button
        MRIBasedButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fprintf(['Citing Information:\n Zhang Tianhao; Nie Binbin; Liu Hua; Shan Baoci;' ...
                'Unified Spatial Normalization of Brain PET Image Using Adaptive Brain Probabilistic Atlas; ' ...
                'European Journal of Nuclear Medicine and Molecular Imaging, 2022\n']);
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 400 && h >500
                app.UIFigure.Position = [round((w-400)/2)+1,round((h-500)/2)+1,400,500];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
            t = timer('StartDelay', 2, ...
                      'ExecutionMode', 'singleShot', ...
                      'TimerFcn', @(~,~) checkUpdateAndClean(), ...
                      'StopFcn',  @(t,~) delete(t));
            start(t);

            function checkUpdateAndClean()
                try
                    checkUpdate(false);
                catch
                end
            end
        end

        % Button pushed function: MRIBasedButton
        function MRIBasedButtonPushed(app, event)
            MRIBased;
        end

        % Button pushed function: TemplateBasedButton
        function TemplateBasedButtonPushed(app, event)
            TemplateBased;
        end

        % Button pushed function: AtlasBasedButton
        function AtlasBasedButtonPushed(app, event)
           AtlasBased;
        end

        % Menu selected function: IntensityNormalizationMenu
        function IntensityNormalizationMenuSelected(app, event)
            PETIntensityNormalize;
        end

        % Menu selected function: ExtractROIValuesMenu
        function ExtractROIValuesMenuSelected(app, event)
            extractROIvalue;
        end

        % Menu selected function: DeleteFilesMenu
        function DeleteFilesMenuSelected(app, event)
            deleteFilesApp;
        end

        % Menu selected function: CheckforUpdatesMenu
        function CheckforUpdatesMenuSelected(app, event)
            checkUpdate(true);
        end

        % Menu selected function: SetOriginToCentroidMenu
        function SetOriginToCentroidMenuSelected(app, event)
            setOriginToCentroid;
        end

        % Menu selected function: SpatialNormalizationQualityCheckMenu
        function SpatialNormalizationQualityCheckMenuSelected(app, event)
            evaluate_registration();
        end

        % Menu selected function: AtlasMergerMenu
        function AtlasMergerMenuSelected(app, event)
            AtlasMergerTool();
        end

        % Menu selected function: SmoothMenu
        function SmoothMenuSelected(app, event)
            spm_jobman('interactive','','spm.spatial.smooth');
        end

        % Menu selected function: OverlayViewerMenu
        function OverlayViewerMenuSelected(app, event)
            roiOverlayViewer
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SetOriginToCentroidMenu = uimenu(app.ToolsMenu);
            app.SetOriginToCentroidMenu.MenuSelectedFcn = createCallbackFcn(app, @SetOriginToCentroidMenuSelected, true);
            app.SetOriginToCentroidMenu.Text = 'Set Origin To Centroid';
            app.SpatialNormalizationQualityCheckMenu = uimenu(app.ToolsMenu);
            app.SpatialNormalizationQualityCheckMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialNormalizationQualityCheckMenuSelected, true);
            app.SpatialNormalizationQualityCheckMenu.Text = 'Spatial Normalization Quality Check';
            app.IntensityNormalizationMenu = uimenu(app.ToolsMenu);
            app.IntensityNormalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @IntensityNormalizationMenuSelected, true);
            app.IntensityNormalizationMenu.Text = 'Intensity Normalization';
            app.SmoothMenu = uimenu(app.ToolsMenu);
            app.SmoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothMenuSelected, true);
            app.SmoothMenu.Text = 'Smooth';
            app.AtlasMergerMenu = uimenu(app.ToolsMenu);
            app.AtlasMergerMenu.MenuSelectedFcn = createCallbackFcn(app, @AtlasMergerMenuSelected, true);
            app.AtlasMergerMenu.Text = 'Atlas Merger';
            app.ExtractROIValuesMenu = uimenu(app.ToolsMenu);
            app.ExtractROIValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractROIValuesMenuSelected, true);
            app.ExtractROIValuesMenu.Text = 'Extract ROI Values';
            app.OverlayViewerMenu = uimenu(app.ToolsMenu);
            app.OverlayViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @OverlayViewerMenuSelected, true);
            app.OverlayViewerMenu.Text = 'Overlay Viewer';
            app.DeleteFilesMenu = uimenu(app.ToolsMenu);
            app.DeleteFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteFilesMenuSelected, true);
            app.DeleteFilesMenu.Text = 'Delete Files';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforUpdatesMenuSelected, true);
            app.CheckforUpdatesMenu.Text = 'Check for Updates';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MRIBasedButton = uibutton(app.GridLayout2, 'push');
            app.MRIBasedButton.ButtonPushedFcn = createCallbackFcn(app, @MRIBasedButtonPushed, true);
            app.MRIBasedButton.Text = 'MRI Based';
            app.TemplateBasedButton = uibutton(app.GridLayout2, 'push');
            app.TemplateBasedButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateBasedButtonPushed, true);
            app.TemplateBasedButton.Text = 'Template Based ';
            app.AtlasBasedButton = uibutton(app.GridLayout2, 'push');
            app.AtlasBasedButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasBasedButtonPushed, true);
            app.AtlasBasedButton.Text = 'Atlas Based';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'PET images.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNBPI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end