classdef deleteFilesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        TextArea           matlab.ui.control.TextArea
        DeleteButton       matlab.ui.control.Button
        SelectFilesButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            filesNames = spm_select(Inf,'any','Select files you want to delete');
            if ~isempty(filesNames)
                app.TextArea.Value = cellstr(filesNames);
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            filesNames = app.TextArea.Value;
            if ~isempty(char(filesNames(1)))
                n = length(filesNames);
                for i=1:n
                    delete(string(filesNames(i)));
                    if i+1<=n
                        newfilesNames = filesNames(i+1:n);
                        app.TextArea.Value = newfilesNames;
                    else
                        app.TextArea.Value = cellstr('');
                    end
                end 
                fprintf('Down: Deleted %d File(s).\n',n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectFilesButton = uibutton(app.GridLayout, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.TextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deleteFilesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end