classdef extractROIvalue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        PETnamesTextArea        matlab.ui.control.TextArea
        SelectPETImagesButton   matlab.ui.control.Button
        GridLayout3             matlab.ui.container.GridLayout
        SelectAtlasImageButton  matlab.ui.control.Button
        AtlasTextArea           matlab.ui.control.TextArea
        RunButton               matlab.ui.control.Button
        SelectFilePathButton    matlab.ui.control.Button
        InputFileNameButton     matlab.ui.control.Button
        FilePathTextArea        matlab.ui.control.TextArea
        InputFileNameEditField  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scnsize = get(0,'ScreenSize');
            w = scnsize(3);
            h = scnsize(4);
            if w > 510 && h >496
                app.UIFigure.Position = [round((w-510)/2)+1,round((h-496)/2)+1,510,490];
            else
                app.UIFigure.Position = [1,1,w,h];
            end
        end

        % Button pushed function: SelectPETImagesButton
        function SelectPETImagesButtonPushed(app, event)
            PETnames = spm_select(Inf,'image','Select PET images'); 
            if ~isempty(PETnames)
                app.PETnamesTextArea.Value = cellstr(PETnames);
            end
        end

        % Button pushed function: SelectAtlasImageButton
        function SelectAtlasImageButtonPushed(app, event)
            str = which('extractROIvalue');
            [filepath,~,~] = fileparts(str);
            atlasName = spm_select(1,'image','Select Reference Region image',{},[filepath,'\Atlas']); 
            if ~isempty(atlasName)
                app.AtlasTextArea.Value = cellstr(atlasName);
                [filepath,name,~] = fileparts(atlasName);
                LablesName = [filepath,'\',name,'_Labels.mat'];
                if exist(LablesName) == 2
                    app.AtlasTextArea.UserData = load(LablesName);
                else
                    app.AtlasTextArea.UserData = 0;
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Extract the ROI values',...
                'Indeterminate','on');
            PETnames = app.PETnamesTextArea.Value;
            atlasName = app.AtlasTextArea.Value;
            ROIvaluefilepath = app.FilePathTextArea.Value;
            filename = app.InputFileNameEditField.Value;

            if isempty(PETnames{1,1})
                uialert(app.UIFigure,'No PET image entered.','Alert');
            elseif isempty(atlasName{1,1})
                uialert(app.UIFigure,'No Reference Region image entered.','Alert');
            elseif isempty(filename)
                uialert(app.UIFigure,'Please Input File Name.','Alert');
            elseif isempty(ROIvaluefilepath)
                uialert(app.UIFigure,'Please Select File Path.','Alert');
            else
                ROIvaluefilefullname = [ROIvaluefilepath{1},'\',filename];
                if isstruct(app.AtlasTextArea.UserData)
                    atlasLabels = app.AtlasTextArea.UserData.Labels;
                    ROIvalueCell = makeROIValueCell(PETnames,atlasName,atlasLabels);
                else
                    ROIvalueCell = makeROIValueCell(PETnames,atlasName);
                end
                writecell(ROIvalueCell,ROIvaluefilefullname);
                fprintf('Down: Extracting ROI Values.\n');
            end
            
            close(d);
          
        end

        % Button pushed function: SelectFilePathButton
        function SelectFilePathButtonPushed(app, event)
            ROIvaluefilepath = uigetdir();
            if ROIvaluefilepath ~= 0
                app.FilePathTextArea.Value = ROIvaluefilepath;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PETnamesTextArea = uitextarea(app.GridLayout2);
            app.SelectPETImagesButton = uibutton(app.GridLayout2, 'push');
            app.SelectPETImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPETImagesButtonPushed, true);
            app.SelectPETImagesButton.Text = 'Select PET Images';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SelectAtlasImageButton = uibutton(app.GridLayout3, 'push');
            app.SelectAtlasImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasImageButtonPushed, true);
            app.SelectAtlasImageButton.Text = 'Select Atlas Image';
            app.AtlasTextArea = uitextarea(app.GridLayout3);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectFilePathButton = uibutton(app.GridLayout3, 'push');
            app.SelectFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilePathButtonPushed, true);
            app.SelectFilePathButton.Text = ' Select File Path';
            app.InputFileNameButton = uibutton(app.GridLayout3, 'push');
            app.InputFileNameButton.Text = {'Input File Name '; '(.txt, .dat, .csv, '; '.xls, .xlsm, .xlsx)'};
            app.FilePathTextArea = uitextarea(app.GridLayout3);
            app.InputFileNameEditField = uieditfield(app.GridLayout3, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extractROIvalue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end